classdef seed_growth < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        nucleationButtonGroup          matlab.ui.container.ButtonGroup
        homogeneusButton               matlab.ui.control.ToggleButton
        radiusButton                   matlab.ui.control.ToggleButton
        randButton                     matlab.ui.control.ToggleButton
        manualButton                   matlab.ui.control.ToggleButton
        colorsEditFieldLabel           matlab.ui.control.Label
        colorsEditField                matlab.ui.control.NumericEditField
        colorsinrowEditFieldLabel      matlab.ui.control.Label
        colorsinrowEditField           matlab.ui.control.NumericEditField
        radiusEditFieldLabel           matlab.ui.control.Label
        radiusEditField                matlab.ui.control.NumericEditField
        colorsEditField_2Label         matlab.ui.control.Label
        colorsEditField_2              matlab.ui.control.NumericEditField
        colorsincolumnEditFieldLabel   matlab.ui.control.Label
        colorsincolumnEditField        matlab.ui.control.NumericEditField
        RowsEditFieldLabel             matlab.ui.control.Label
        RowsEditField                  matlab.ui.control.NumericEditField
        ColumnsEditFieldLabel          matlab.ui.control.Label
        ColumnsEditField               matlab.ui.control.NumericEditField
        StartButton                    matlab.ui.control.Button
        BoundaryconditionButtonGroup   matlab.ui.container.ButtonGroup
        PeriodicButton                 matlab.ui.control.ToggleButton
        AbsorbingButton                matlab.ui.control.ToggleButton
        neighbourhoodButtonGroup       matlab.ui.container.ButtonGroup
        VonNeumannButton               matlab.ui.control.ToggleButton
        MooreButton                    matlab.ui.control.ToggleButton
        Hexdrop                        matlab.ui.control.DropDown
        HexagonalButton                matlab.ui.control.ToggleButton
        PentagonalButton               matlab.ui.control.ToggleButton
        MonteCarloiterationsEditFieldLabel  matlab.ui.control.Label
        MonteCarloiterationsEditField  matlab.ui.control.NumericEditField
        ktEditFieldLabel               matlab.ui.control.Label
        ktEditField                    matlab.ui.control.NumericEditField
        timestepEditFieldLabel         matlab.ui.control.Label
        timestepEditField              matlab.ui.control.NumericEditField
        lasttimeEditFieldLabel         matlab.ui.control.Label
        lasttimeEditField              matlab.ui.control.NumericEditField
        precentEditFieldLabel          matlab.ui.control.Label
        precentEditField               matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        tab;
        cmap;
        neighbourhood = 1;
    end

    
    methods (Access = public)
        
        function results = draw(app)

                    I = image(app.tab, 'Parent', app.UIAxes);
                    app.tab(app.tab~=0);
                    %I = imshow(app.tab, 'Parent', app.UIAxes);
                    colormap(app.UIAxes, app.cmap);
                    wsp = size(app.tab, 1)/size(app.tab, 2);               
                    if size(app.tab, 1)>size(app.tab, 2)
                        app.UIAxes.XLim = [0 I.XData(2)*wsp];
                        app.UIAxes.YLim = [0 I.YData(2)];
                    elseif size(app.tab, 1) < size(app.tab, 2)
                        app.UIAxes.XLim = [0 I.XData(2)];
                        app.UIAxes.YLim = [0 I.YData(2)*(1/wsp)];                        
                    else
                        app.UIAxes.XLim = [0 I.XData(2)];
                        app.UIAxes.YLim = [0 I.YData(2)];
                    end
                    
        end


        function results1 = recrystalizationInitialization(app, boundsChoice)
                    A = 86710969050178.5;
                    B = 9.41268203527779;
                    
                    
                    %declaration
                    dislocationTab = zeros(size(app.tab));
                    recrystalizationTab = zeros(size(app.tab));
                    
                    times = app.timestepEditField.Value:app.timestepEditField.Value:app.lasttimeEditField.Value;
                    
                    ro = A/B + (1- A/B)*exp(1).^(-B*times);
                    deltaRo = diff(ro);
                    extended_tab = bounds(app.tab, boundsChoice);
                    criticalDislocationValue = 4.21584e12/(size(app.tab, 1)*size(app.tab, 2));
                
                    %Average dislocation value
                    averageDislocation = (deltaRo/(size(app.tab, 1)*size(app.tab, 2)));
                    forEveryCell = app.precentEditField.Value*0.01*averageDislocation;
                    restOfForEveryCell = (1-(app.precentEditField.Value*0.01))*deltaRo;
                    fileID = fopen('plik.txt','w');
                    fprintf( fileID, 'times\t\tsum of dislocation\n');


                    for i=1:size(deltaRo, 2)
                        %dislocation gun
                        dislocationTab = dislocationTab + forEveryCell(i);
                        
                        %rest pool throw
                        while(restOfForEveryCell(i)>0)
                            precent =(rand()+randi([5, 10]))/20;
                            p = randi(100);
                            row = randi(size(app.tab, 1));
                            column = randi(size(app.tab, 2));
                            neighbours = countNeighbours(row+1,column+1,bounds(app.tab, boundsChoice), app.neighbourhood, -2);
                            if p<80
                                while(numel(unique(neighbours)) == 1)
                                    row = randi(size(app.tab, 1));
                                    column = randi(size(app.tab, 2));
                                    neighbours = countNeighbours(row+1,column+1,bounds(app.tab, boundsChoice), app.neighbourhood, -2);                                   
                                end
                                
                            else
                                while(numel(unique(neighbours)) ~= 1)
                                    row = randi(size(app.tab, 1));
                                    column = randi(size(app.tab, 2));
                                    neighbours = countNeighbours(row+1,column+1,bounds(app.tab, boundsChoice), app.neighbourhood, -2);                                    
                                end
                            end
                            %precent*restOfForEveryCell
                            if restOfForEveryCell(i)-precent*restOfForEveryCell(i)>0
                                dislocationTab(row, column) = dislocationTab(row, column) + precent*restOfForEveryCell(i);
                                restOfForEveryCell(i) = restOfForEveryCell(i)-precent*restOfForEveryCell(i);
                            else
                                dislocationTab(row, column) = dislocationTab(row, column) + restOfForEveryCell(i);
                                restOfForEveryCell(i) = 0;
                            end
                        end
                
                        %check if dislocation value exceeded
                        [row, column] = find(dislocationTab>criticalDislocationValue);
                        for j=1:size(row, 1)
                            %[row(j), column(j), countNeighbours(row(j)+1, column(j)+1, extended_tab, neighbourhood, tab(row, column))]
                            if countNeighbours(row(j)+1, column(j)+1, extended_tab, app.neighbourhood, app.tab(row(j), column(j)))~=0
                                recrystalizationTab(row(j), column(j)) = -2;
                                dislocationTab(row(j), column(j)) = 0;
                            end
                        end
                
                        %second condition
                        for row=1:size(app.tab, 1)
                            for column = 1:size(app.tab, 2)
                                neighbours = countNeighbours(row+1,column+1, bounds(recrystalizationTab, boundsChoice), app.neighbourhood, -2);
                
                                if ~isempty(find(neighbours==-1))
                                    if ~isempty(find(countNeighbours(row+1, column+1, bounds(dislocationTab,boundsChoice), app.neighbourhood, -2)>dislocationTab(row, column)))
                                        recrystalizationTab(row, column) = -2;  
                                        dislocationTab(row, column) = 0;
                                    end
                                end
                            end
                        end
                
                
                        %drawing
                        recrystalizationTab(recrystalizationTab==-1)=-10;
                        recrystalizationTab(recrystalizationTab==-2) = -1;  %żeby były w następnym kroku czasowym
                        %if dislocation is 0, cell is tab value
                        recrystalizationTab(recrystalizationTab==0) = app.tab(recrystalizationTab==0);
                        
                        image(app.UIAxes, recrystalizationTab);  
%                         dislocationTab
%                         image(dislocationTab);
                        pause(0.1);
                        fprintf( fileID, '%f\t%f\n', times(i), sum(sum(dislocationTab)));

                    end
                        
                    fclose(fileID);


         end

  end


    methods (Access = private)

        % Selection changed function: nucleationButtonGroup
        function nucleationButtonGroupSelectionChanged(app, event)
            selectedButton = app.nucleationButtonGroup.SelectedObject;
           
            if selectedButton == app.randButton
                app.cmap = colormapCreating(app.colorsEditField.Value);
                app.tab = createTab(app.tab, 1, app.colorsEditField.Value, app.RowsEditField.Value, app.ColumnsEditField.Value);
                app.draw();
            elseif selectedButton == app.homogeneusButton
                app.cmap = colormapCreating(10000);
                app.tab = createTab(app.tab, 2, [app.colorsinrowEditField.Value, app.colorsincolumnEditField.Value], app.RowsEditField.Value, app.ColumnsEditField.Value);
                app.draw();
            elseif selectedButton == app.radiusButton
                app.cmap = colormapCreating(app.colorsEditField_2.Value);
                app.tab = createTab(app.tab, 3, [app.colorsEditField_2.Value, app.radiusEditField.Value], app.RowsEditField.Value, app.ColumnsEditField.Value);
                app.draw();
            
            elseif selectedButton == app.manualButton
                app.cmap = colormapCreating(100);
                app.tab = createTab(app.tab,4, 10, app.RowsEditField.Value, app.ColumnsEditField.Value);
                app.draw();
            end
                
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            completed = -1;
            while(completed == -1)
                if app.PeriodicButton.Value == 1
                    boundsChoice = 1;
                    extended_tab = bounds(app.tab, 1);
                elseif app.AbsorbingButton.Value == 1
                    boundsChoice = 2;
                    extended_tab = bounds(app.tab, 2);
                end
                [app.tab, completed] = nextStepTab(extended_tab, app.neighbourhood);
                app.draw();
                pause(0.05);
                
            end            
            
            for i=1:app.MonteCarloiterationsEditField.Value
                app.tab = countEnergyTab(app.tab, boundsChoice, app.neighbourhood, app.ktEditField.Value);
                app.draw();
                pause(0.1);
            end
            boundsChoice = uint8(boundsChoice);
            app.recrystalizationInitialization(boundsChoice);


        end

        % Callback function: Hexdrop, neighbourhoodButtonGroup
        function neighbourhoodButtonGroupSelectionChanged(app, event)
            neighbours = 1; 
            if app.VonNeumannButton.Value ==1
                    neighbours = 1;
                elseif app.MooreButton.Value == 1
                    neighbours = 2;
                elseif app.HexagonalButton.Value == 1
                    if app.Hexdrop.Value == "left"
                        neighbours = 31;
                    elseif app.Hexdrop.Value == "right"
                        neighbours = 32;
                    elseif app.Hexdrop.Value == "rand"
                        neighbours = 33;
                    end
                elseif app.PentagonalButton.Value == 1
                    neighbours = 4;

                elseif app.Radius1Button.Value == 1
                    neighbours = 5;
             end
             
            app.neighbourhood = neighbours;            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.nucleationButtonGroup = uibuttongroup(app.UIFigure);
            app.nucleationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @nucleationButtonGroupSelectionChanged, true);
            app.nucleationButtonGroup.Title = 'nucleation';
            app.homogeneusButton = uitogglebutton(app.nucleationButtonGroup);
            app.homogeneusButton.Text = 'homogeneus';
            app.homogeneusButton.Value = true;
            app.radiusButton = uitogglebutton(app.nucleationButtonGroup);
            app.radiusButton.Text = 'radius';
            app.randButton = uitogglebutton(app.nucleationButtonGroup);
            app.randButton.Text = 'rand';
            app.manualButton = uitogglebutton(app.nucleationButtonGroup);
            app.manualButton.Text = 'manual';
            app.colorsEditFieldLabel = uilabel(app.nucleationButtonGroup);
            app.colorsEditFieldLabel.Text = 'colors';
            app.colorsEditField = uieditfield(app.nucleationButtonGroup, 'numeric');
            app.colorsEditField.Limits = [1 Inf];
            app.colorsEditField.Value = 50;
            app.colorsinrowEditFieldLabel = uilabel(app.nucleationButtonGroup);
            app.colorsinrowEditFieldLabel.Text = 'colors in row';
            app.colorsinrowEditField = uieditfield(app.nucleationButtonGroup, 'numeric');
            app.colorsinrowEditField.Value = 2;
            app.radiusEditFieldLabel = uilabel(app.nucleationButtonGroup);
            app.radiusEditFieldLabel.Text = 'radius';
            app.radiusEditField = uieditfield(app.nucleationButtonGroup, 'numeric');
            app.radiusEditField.Value = 1;
            app.colorsEditField_2Label = uilabel(app.nucleationButtonGroup);
            app.colorsEditField_2Label.Text = 'colors';
            app.colorsEditField_2 = uieditfield(app.nucleationButtonGroup, 'numeric');
            app.colorsEditField_2.Value = 5;
            app.colorsincolumnEditFieldLabel = uilabel(app.nucleationButtonGroup);
            app.colorsincolumnEditFieldLabel.Text = 'colors in column';
            app.colorsincolumnEditField = uieditfield(app.nucleationButtonGroup, 'numeric');
            app.colorsincolumnEditField.Value = 2;
            app.RowsEditFieldLabel = uilabel(app.UIFigure);
            app.RowsEditFieldLabel.Text = 'Rows';
            app.RowsEditField = uieditfield(app.UIFigure, 'numeric');
            app.RowsEditField.Limits = [5 Inf];
            app.RowsEditField.Value = 100;
            app.ColumnsEditFieldLabel = uilabel(app.UIFigure);
            app.ColumnsEditFieldLabel.Text = 'Columns';
            app.ColumnsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ColumnsEditField.Limits = [5 Inf];
            app.ColumnsEditField.Value = 100;
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = {'Start'; ''};
            app.BoundaryconditionButtonGroup = uibuttongroup(app.UIFigure);
            app.BoundaryconditionButtonGroup.Title = 'Boundary condition';
            app.PeriodicButton = uitogglebutton(app.BoundaryconditionButtonGroup);
            app.PeriodicButton.Text = 'Periodic';
            app.PeriodicButton.Value = true;
            app.AbsorbingButton = uitogglebutton(app.BoundaryconditionButtonGroup);
            app.AbsorbingButton.Text = 'Absorbing';
            app.neighbourhoodButtonGroup = uibuttongroup(app.UIFigure);
            app.neighbourhoodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @neighbourhoodButtonGroupSelectionChanged, true);
            app.neighbourhoodButtonGroup.Title = 'neighbourhood';
            app.VonNeumannButton = uitogglebutton(app.neighbourhoodButtonGroup);
            app.VonNeumannButton.Text = 'Von Neumann';
            app.VonNeumannButton.Value = true;
            app.MooreButton = uitogglebutton(app.neighbourhoodButtonGroup);
            app.MooreButton.Text = 'Moore';
            app.Hexdrop = uidropdown(app.neighbourhoodButtonGroup);
            app.Hexdrop.Items = {'left', 'right', 'rand'};
            app.Hexdrop.ValueChangedFcn = createCallbackFcn(app, @neighbourhoodButtonGroupSelectionChanged, true);
            app.Hexdrop.Value = 'left';
            app.HexagonalButton = uitogglebutton(app.neighbourhoodButtonGroup);
            app.HexagonalButton.Text = {'Hexagonal'; ''};
            app.PentagonalButton = uitogglebutton(app.neighbourhoodButtonGroup);
            app.PentagonalButton.Text = {'Pentagonal'; ''};
            app.MonteCarloiterationsEditFieldLabel = uilabel(app.UIFigure);
            app.MonteCarloiterationsEditFieldLabel.Text = 'Monte Carlo iterations';
            app.MonteCarloiterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.MonteCarloiterationsEditField.Limits = [0 Inf];
            app.MonteCarloiterationsEditField.Value = 10;
            app.ktEditFieldLabel = uilabel(app.UIFigure);
            app.ktEditFieldLabel.Text = 'kt';
            app.ktEditField = uieditfield(app.UIFigure, 'numeric');
            app.ktEditField.Limits = [0.1 6];
            app.ktEditField.Value = 1;
            app.timestepEditFieldLabel = uilabel(app.UIFigure);
            app.timestepEditFieldLabel.Text = 'time step';
            app.timestepEditField = uieditfield(app.UIFigure, 'numeric');
            app.timestepEditField.Limits = [0 Inf];
            app.timestepEditField.Value = 0.1;
            app.lasttimeEditFieldLabel = uilabel(app.UIFigure);
            app.lasttimeEditFieldLabel.Text = 'last time';
            app.lasttimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.lasttimeEditField.Limits = [0 Inf];
            app.lasttimeEditField.Value = 0.5;
            app.precentEditFieldLabel = uilabel(app.UIFigure);
            app.precentEditFieldLabel.Text = 'precent';
            app.precentEditField = uieditfield(app.UIFigure, 'numeric');
            app.precentEditField.Limits = [0 100];
            app.precentEditField.Value = 80;
        end
    end

    methods (Access = public)

        % Construct app
        function app = seed_growth

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end